classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_4       matlab.ui.container.Menu
        Menu_5       matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        UIAxes       matlab.ui.control.UIAxes
        UITable      matlab.ui.control.Table
        Button       matlab.ui.control.StateButton
        Button_2     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Label        matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        EditField_2  matlab.ui.control.NumericEditField
        Label_3      matlab.ui.control.Label
        EditField_3  matlab.ui.control.NumericEditField
        Button_4     matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        pButton      matlab.ui.control.RadioButton
        sButton      matlab.ui.control.RadioButton
        CheckBox     matlab.ui.control.CheckBox
        CheckBox_2   matlab.ui.control.CheckBox
    end


    properties (Access = public)
        datum % Description
    end

    methods (Access = private)
    
        function results = dosome(app)
            group = {'Acrylic' 'Pt' 'TiSi2' 'W'};
            t = app.UITable;
            t.ColumnFormat = {group 'numeric'};
            t.ColumnEditable = true;
            d = {'Acrylic' 1};
            t.Data = d;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.datum = preread();
            dosome(app);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            dosome(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            data = app.UITable.Data;
%             app.TextArea.Value = data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            data = app.UITable.Data;
            l = size(data,1);
            data{l+1,1} = 'Acrylic';
            data{l+1,2} = 1;
            app.UITable.Data = data;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            data = app.UITable.Data;
            l = size(data,1);
            if (l > 1)
                data(l,:)=[];
                app.UITable.Data = data;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            lambs = app.EditField.Value;
            lambe = app.EditField_2.Value;
            dlamb = app.EditField_3.Value;
            data = app.UITable.Data;
            layers = size(data,1);
            material = data(:,1);
            d = data(:,2);
            [h,R,T] = multi_reflect(material,lambs,lambe,dlamb,layers,d,app.datum,app.pButton.Value);
%             plot(app.UIAxes,h,R,'b',h,T,'r');
            if app.CheckBox.Value == true && app.CheckBox_2.Value == false
                plot(app.UIAxes,h,R,'b');
            elseif app.CheckBox.Value == false && app.CheckBox_2.Value == true
                plot(app.UIAxes,h,T,'b');
            elseif app.CheckBox.Value == true && app.CheckBox_2.Value == true
                plot(app.UIAxes,h,R,'b',h,T,'r');
            else
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '新建';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.Text = '保存';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '自定义';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '帮助';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图线')
            xlabel(app.UIAxes, '波长')
            ylabel(app.UIAxes, '比率')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'类型'; '厚度'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '还原';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '波长下限';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 380;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '波长上限';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 780;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '波长变动';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.Value = 10;
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '作图';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '偏振态';
            app.pButton = uiradiobutton(app.ButtonGroup);
            app.pButton.Text = 'p偏振';
            app.pButton.Value = true;
            app.sButton = uiradiobutton(app.ButtonGroup);
            app.sButton.Text = 's偏振';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '反射率';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '透射率';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end